classdef operaciones_final_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        origenz                         matlab.ui.control.NumericEditField
        OrigenzposicinLabel             matlab.ui.control.Label
        origenh                         matlab.ui.control.NumericEditField
        OrigenhposicinLabel             matlab.ui.control.Label
        origenx                         matlab.ui.control.NumericEditField
        OrigenxposicinLabel             matlab.ui.control.Label
        SecuenciaresultanteznLabel      matlab.ui.control.Label
        SecuenciahnLabel                matlab.ui.control.Label
        SecuenciaxnLabel                matlab.ui.control.Label
        SecuenciaznLabel                matlab.ui.control.Label
        ResultadoButton                 matlab.ui.control.Button
        OperacionesDropDown             matlab.ui.control.DropDown
        OperacionesDropDownLabel        matlab.ui.control.Label
        GRFICASDEENTRADASALIDALabel     matlab.ui.control.Label
        SemuestralasealresultanteLabel  matlab.ui.control.Label
        SecuenciaresultanteEditField    matlab.ui.control.EditField
        SEALDESALIDALabel               matlab.ui.control.Label
        ValordenEditField               matlab.ui.control.EditField
        ValordenEditFieldLabel          matlab.ui.control.Label
        IntroduceelvalordenLabel        matlab.ui.control.Label
        SecuenciahnEditField            matlab.ui.control.EditField
        SecuenciahnEditFieldLabel       matlab.ui.control.Label
        SecuenciaxnEditField            matlab.ui.control.EditField
        SecuenciaxnEditFieldLabel       matlab.ui.control.Label
        IntroducelasealdeentradasegncorrespondaLabel  matlab.ui.control.Label
        SEALESDEENTRADALabel            matlab.ui.control.Label
        SeleccionalaoperacinquedeseasrealizarLabel  matlab.ui.control.Label
        OPERACIONESLabel                matlab.ui.control.Label
        PRCTICAOPERACIONESBSICASLabel   matlab.ui.control.Label
        Graficahn                       matlab.ui.control.UIAxes
        Graficaxn                       matlab.ui.control.UIAxes
        Graficazn                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OperacionesDropDown
        function OperacionesDropDownValueChanged(app, event)
            value = app.OperacionesDropDown.Value;
            if value == "Suma"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'on';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.SecuenciahnEditFieldLabel.Visible = 'on';
                app.SecuenciahnEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenhposicinLabel.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
                app.origenh.Visible = 'on';
            end
            if value == "Resta"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn, 'reset');
                cla(app.Graficahn, 'reset');
                cla(app.Graficazn, 'reset');
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.SecuenciahnEditFieldLabel.Visible = 'on';
                app.SecuenciahnEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenhposicinLabel.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
                app.origenh.Visible = 'on';
            end
            if value == "Amplificación"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.ValordenEditFieldLabel.Visible = 'on';
                app.ValordenEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Atenuación"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.ValordenEditFieldLabel.Visible = 'on';
                app.ValordenEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Reflejo"
                app.ValordenEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Desplazamiento"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.ValordenEditFieldLabel.Visible = 'on';
                app.ValordenEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Diezmación"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.ValordenEditFieldLabel.Visible = 'on';
                app.ValordenEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Interpolación a cero"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.ValordenEditFieldLabel.Visible = 'on';
                app.ValordenEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Interpolación a escalón"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.ValordenEditFieldLabel.Visible = 'on';
                app.ValordenEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Interpolación lineal"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                app.OrigenhposicinLabel.Visible = 'off';
                app.OrigenxposicinLabel.Visible = 'off';
                app.origenx.Visible = 'off';
                app.origenh.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.IntroduceelvalordenLabel.Visible = 'on';
                app.ValordenEditFieldLabel.Visible = 'on';
                app.ValordenEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
            end
            if value == "Convolución"
                app.SecuenciaxnEditFieldLabel.Visible = 'off';
                app.SecuenciaxnEditField.Visible = 'off';
                app.SecuenciaxnEditField.Value = " ";
                app.SecuenciahnEditFieldLabel.Visible = 'off';
                app.SecuenciahnEditField.Visible = 'off';
                app.SecuenciahnEditField.Value = " ";
                app.IntroduceelvalordenLabel.Visible = 'off';
                app.ValordenEditFieldLabel.Visible = 'off';
                app.ValordenEditField.Visible = 'off';
                app.ValordenEditField.Value = " ";
                app.ResultadoButton.Visible = 'off';
                app.SecuenciaresultanteEditField.Visible = 'off';
                app.SecuenciaznLabel.Visible = 'off';
                cla(app.Graficaxn);
                cla(app.Graficahn);
                cla(app.Graficazn);
                app.SecuenciaxnEditFieldLabel.Visible = 'on';
                app.SecuenciaxnEditField.Visible = 'on';
                app.SecuenciahnEditFieldLabel.Visible = 'on';
                app.SecuenciahnEditField.Visible = 'on';
                app.ResultadoButton.Visible = 'on';
                app.OrigenhposicinLabel.Visible = 'on';
                app.OrigenxposicinLabel.Visible = 'on';
                app.origenx.Visible = 'on';
                app.origenh.Visible = 'on';
            end
        end

        % Button pushed function: ResultadoButton
        function ResultadoButtonPushed(app, event)
            app.SecuenciaresultanteEditField.Value = "";
            app.SecuenciaznLabel.Visible = 'on';
            app.SecuenciaresultanteEditField.Visible = 'on';
            app.OrigenzposicinLabel.Visible = 'on';
            app.origenz.Visible='on';
            value = app.OperacionesDropDown.Value;
            if value == "Suma"
                %posicion del origen de x
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %grafica x tomando en cuenta el origen
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %grafica h tomando en cuenta el origen
                posh = app.origenh.Value;
                vecthn = textscan(app.SecuenciahnEditField.Value, '%f','Delimiter',',');
                vecthn = permute(vecthn{1},[2,1]);
                minh = 0;
                ih = posh-1;
                for f=1:ih
                    minh = minh+1;
                end
                limih= minh*(-1);
                maxh = 0;
                ih = posh+1;
                tamh=length(vecthn);
                for f=ih:tamh
                    maxh = maxh+1;
                end
                limsh= maxh;
                interh = limih:1:limsh;
                disp(vecthn);
                stem(app.Graficahn,interh,vecthn);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 
                %codigo para completar vectores
                limix = limix*(-1);
                limih = limih*(-1);
                cx=limih-limix;
                auxx = 1*cx;
                ch=limix-limih;
                auxh = 1*ch;
                %rellena los vectores antes de la pos de origen
                if limix<limih                    
                    for f=1:cx
                        auxx(f) = 0;
                    end
                    t=tamx+length(auxx);
                    cx=cx+1;
                    i=1;
                    for f=cx:t
                        auxx(f) = vectxn(i);
                        i=i+1;
                    end
                    vectxn=auxx;
                elseif tamh<tamx
                    for f=1:ch
                        auxh(f) = 0;
                    end
                    t=tamh+length(auxh);
                    ch=ch+1;
                    i=1;
                    for f=ch:t
                        auxh(f) = vecthn(i);
                        i=i+1;
                    end
                    vecthn=auxh;
                end
                %rellena los vectores despues de la pos de origen
                if limsx<limsh
                    c=posx+limsh;
                    i=posx+limsx;
                    for f=i:c
                        vectxn(f) = 0;
                    end
                elseif limsh<limsx
                    c=posh+limsx;
                    i=posh+limsh;
                    for f=i:c
                        vecthn(f) = 0;
                    end
                end
                disp(vectxn)
                disp(vecthn)
                vectzn=vectxn+vecthn;
                disp(vectzn);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %grafica z tomando en cuenta el origen
                if posx>posh
                    posz = length(auxx) + posx;
                    app.origenz.Value=posz;
                else
                    posz = length(auxh) + posh;
                    app.origenz.Value=posz;
                end
                minz = 0;
                iz = posz-1;
                for f=1:iz
                    minz = minz+1;
                end
                limiz= minz*(-1);
                maxz = 0;
                iz = posz+1;
                tamz=length(vectzn);
                for f=iz:tamz
                    maxz = maxz+1;
                end
                limsz= maxz;
                interz = limiz:1:limsz;
                stem(app.Graficazn,interz,vectzn);
                app.SecuenciaresultanteEditField.Value=mat2str(vectzn);
            elseif value == "Resta"
               posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                posh = app.origenh.Value;
                vecthn = textscan(app.SecuenciahnEditField.Value, '%f','Delimiter',',');
                vecthn = permute(vecthn{1},[2,1]);
                minh = 0;
                ih = posh-1;
                for f=1:ih
                    minh = minh+1;
                end
                limih= minh*(-1);
                maxh = 0;
                ih = posh+1;
                tamh=length(vecthn);
                for f=ih:tamh
                    maxh = maxh+1;
                end
                limsh= maxh;
                interh = limih:1:limsh;
                disp(vecthn);
                stem(app.Graficahn,interh,vecthn);
                limix = limix*(-1);
                limih = limih*(-1);
                cx=limih-limix;
                auxx = 1*cx;
                ch=limix-limih;
                auxh = 1*ch;
                if limix<limih                    
                    for f=1:cx
                        auxx(f) = 0;
                    end
                    t=tamx+length(auxx);
                    cx=cx+1;
                    i=1;
                    for f=cx:t
                        auxx(f) = vectxn(i);
                        i=i+1;
                    end
                    vectxn=auxx;
                elseif tamh<tamx
                    for f=1:ch
                        auxh(f) = 0;
                    end
                    t=tamh+length(auxh);
                    ch=ch+1;
                    i=1;
                    for f=ch:t
                        auxh(f) = vecthn(i);
                        i=i+1;
                    end
                    vecthn=auxh;
                end
                
                if limsx<limsh
                    c=posx+limsh;
                    i=posx+limsx;
                    for f=i:c
                        vectxn(f) = 0;
                    end
                elseif tamh<tamx
                    c=posh+limsx;
                    i=posh+limsh;
                    for f=i:c
                        vecthn(f) = 0;
                    end
                end
                vectzn=vectxn-vecthn;
                disp(vectzn);
                if posx>posh
                    posz = length(auxx) + posx;
                    app.origenz.Value=posz;
                else
                    posz = length(auxh) + posh;
                    app.origenz.Value=posz;
                end
                minz = 0;
                iz = posz-1;
                for f=1:iz
                    minz = minz+1;
                end
                limiz= minz*(-1);
                maxz = 0;
                iz = posz+1;
                tamz=length(vectzn);
                for f=iz:tamz
                    maxz = maxz+1;
                end
                limsz= maxz;
                interz = limiz:1:limsz;
                stem(app.Graficazn,interz,vectzn);
                app.SecuenciaresultanteEditField.Value=mat2str(vectzn);
            elseif value == "Amplificación"
               posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                valorn = str2double(app.ValordenEditField.Value);
                vectzn=vectxn .* valorn;
                disp(vectzn);
                posz = posx;
                minz = 0;
                iz = posz-1;
                for f=1:iz
                    minz = minz+1;
                end
                limiz= minz*(-1);
                maxz = 0;
                iz = posz+1;
                tamz=length(vectzn);
                for f=iz:tamz
                    maxz = maxz+1;
                end
                limsz= maxz;
                interz = limiz:1:limsz;
                stem(app.Graficazn,interz,vectzn);
                app.SecuenciaresultanteEditField.Value=mat2str(vectzn);
                app.origenz.Value=posz;
            elseif value == "Atenuación"
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                valorn = str2double(app.ValordenEditField.Value);
                vectzn=vectxn ./ valorn;
                disp(vectzn);
                posz = posx;
                minz = 0;
                iz = posz-1;
                for f=1:iz
                    minz = minz+1;
                end
                limiz= minz*(-1);
                maxz = 0;
                iz = posz+1;
                tamz=length(vectzn);
                for f=iz:tamz
                    maxz = maxz+1;
                end
                limsz= maxz;
                interz = limiz:1:limsz;
                stem(app.Graficazn,interz,vectzn);
                app.SecuenciaresultanteEditField.Value=mat2str(vectzn);
                app.origenz.Value=posz;
            elseif value == "Reflejo"
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                vectzn=fliplr(vectxn);
                disp(vectzn);
                posz = tamx-posx+1;
                minz = 0;
                iz = posz-1;
                for f=1:iz
                    minz = minz+1;
                end
                limiz= minz*(-1);
                maxz = 0;
                iz = posz+1;
                tamz=length(vectzn);
                for f=iz:tamz
                    maxz = maxz+1;
                end
                limsz= maxz;
                interz = limiz:1:limsz;
                stem(app.Graficazn,interz,vectzn);
                app.SecuenciaresultanteEditField.Value=mat2str(vectzn);
                app.origenz.Value=posz;
            elseif value == "Desplazamiento"
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                valorn = str2double(app.ValordenEditField.Value);
                if valorn>0
                    limiz = limix-valorn;
                    limsz = limsx-valorn;
                    interz = limiz:1:limsz;
                else
                    limiz = limix-valorn;
                    limsz = limsx-valorn;
                    interz = limiz:1:limsz;
                end
                vectzn=vectxn;
                stem(app.Graficazn,interz,vectzn);
                app.SecuenciaresultanteEditField.Value=mat2str(vectzn);
            elseif value == "Diezmación"
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                valorn = str2double(app.ValordenEditField.Value);
                vectz=[];
                
                for i=1:valorn:length(vectxn)
                    elem=vectxn(i);
                    vectz=[vectz, elem];
                end
                stem(app.Graficazn,vectz);
                app.SecuenciaresultanteEditField.Value=mat2str(vectz);
                
                
            elseif value == "Interpolación a cero"
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                valorn = str2double(app.ValordenEditField.Value);
                s_inter0= vectxn; 
                s_inter0(valorn,:) =0;
                s_inter0=s_inter0(:).';
                disp(s_inter0);
                %interz=-10:1:2;
                stem(app.Graficazn,s_inter0);
                app.SecuenciaresultanteEditField.Value=mat2str(s_inter0);
                
            elseif value == "Interpolación a escalón"
               posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                valorn = str2double(app.ValordenEditField.Value);
                s_inter0 = vectxn;
                s_interE = repelem(s_inter0,valorn);
                disp(s_interE);
                stem(app.Graficazn,s_interE);
                app.SecuenciaresultanteEditField.Value=mat2str(s_interE);
                
            elseif value == "Interpolación lineal"
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                valorn = str2double(app.ValordenEditField.Value);
                y=interp(vectxn,valorn);
                disp(y);
                stem(app.Graficazn,y);
                app.SecuenciaresultanteEditField.Value=mat2str(y);
                
            elseif value == "Convolución"
                posx = app.origenx.Value;
                vectxn = textscan(app.SecuenciaxnEditField.Value, '%f','Delimiter',',');
                vectxn = permute(vectxn{1},[2,1]);
                minx = 0;
                ix = posx-1;
                for f=1:ix
                    minx = minx+1;
                end
                limix= minx*(-1);
                maxx = 0;
                ix = posx+1;
                tamx=length(vectxn);
                for f=ix:tamx
                    maxx = maxx+1;
                end
                limsx= maxx;
                interx = limix:1:limsx;
                disp(vectxn);
                stem(app.Graficaxn,interx,vectxn)
                posh = app.origenh.Value;
                vecthn = textscan(app.SecuenciahnEditField.Value, '%f','Delimiter',',');
                vecthn = permute(vecthn{1},[2,1]);
                minh = 0;
                ih = posh-1;
                for f=1:ih
                    minh = minh+1;
                end
                limih= minh*(-1);
                maxh = 0;
                ih = posh+1;
                tamh=length(vecthn);
                for f=ih:tamh
                    maxh = maxh+1;
                end
                limsh= maxh;
                interh = limih:1:limsh;
                disp(vecthn);
                stem(app.Graficahn,interh,vecthn);
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                app.origenz.Value=11;
                vectzn=conv(vectxn,vecthn);
                disp(vectzn);
                stem(app.Graficazn,vectzn);
                app.SecuenciaresultanteEditField.Value=mat2str(vectzn);
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Graficazn = uiaxes(app.UIFigure);
            xlabel(app.Graficazn, 'X')
            ylabel(app.Graficazn, 'Y')
            zlabel(app.Graficazn, 'Z')
            app.Graficaxn = uiaxes(app.UIFigure);
            xlabel(app.Graficaxn, 'X')
            ylabel(app.Graficaxn, 'Y')
            zlabel(app.Graficaxn, 'Z')
            app.Graficahn = uiaxes(app.UIFigure);
            xlabel(app.Graficahn, 'X')
            ylabel(app.Graficahn, 'Y')
            zlabel(app.Graficahn, 'Z')
            app.PRCTICAOPERACIONESBSICASLabel = uilabel(app.UIFigure);
            app.PRCTICAOPERACIONESBSICASLabel.Text = 'PRÁCTICA OPERACIONES BÁSICAS';
            app.OPERACIONESLabel = uilabel(app.UIFigure);
            app.OPERACIONESLabel.Text = '1. OPERACIONES';
            app.SeleccionalaoperacinquedeseasrealizarLabel = uilabel(app.UIFigure);
            app.SeleccionalaoperacinquedeseasrealizarLabel.Text = 'Selecciona la operación que deseas realizar:';
            app.SEALESDEENTRADALabel = uilabel(app.UIFigure);
            app.SEALESDEENTRADALabel.Text = '2. SEÑALES DE ENTRADA ';
            app.IntroducelasealdeentradasegncorrespondaLabel = uilabel(app.UIFigure);
            app.IntroducelasealdeentradasegncorrespondaLabel.Text = 'Introduce la señal de entrada según corresponda:';
            app.SecuenciaxnEditFieldLabel = uilabel(app.UIFigure);
            app.SecuenciaxnEditFieldLabel.Visible = 'off';
            app.SecuenciaxnEditFieldLabel.Text = 'Secuencia x(n)';
            app.SecuenciaxnEditField = uieditfield(app.UIFigure, 'text');
            app.SecuenciaxnEditField.Visible = 'off';
            app.SecuenciahnEditFieldLabel = uilabel(app.UIFigure);
            app.SecuenciahnEditFieldLabel.Visible = 'off';
            app.SecuenciahnEditFieldLabel.Text = 'Secuencia h(n)';
            app.SecuenciahnEditField = uieditfield(app.UIFigure, 'text');
            app.SecuenciahnEditField.Visible = 'off';
            app.IntroduceelvalordenLabel = uilabel(app.UIFigure);
            app.IntroduceelvalordenLabel.Visible = 'off';
            app.IntroduceelvalordenLabel.Text = 'Introduce el valor de n:';
            app.ValordenEditFieldLabel = uilabel(app.UIFigure);
            app.ValordenEditFieldLabel.Visible = 'off';
            app.ValordenEditFieldLabel.Text = 'Valor de n';
            app.ValordenEditField = uieditfield(app.UIFigure, 'text');
            app.ValordenEditField.Visible = 'off';
            app.SEALDESALIDALabel = uilabel(app.UIFigure);
            app.SEALDESALIDALabel.Text = '3. SEÑAL DE SALIDA';
            app.SecuenciaresultanteEditField = uieditfield(app.UIFigure, 'text');
            app.SecuenciaresultanteEditField.Visible = 'off';
            app.SemuestralasealresultanteLabel = uilabel(app.UIFigure);
            app.SemuestralasealresultanteLabel.Text = 'Se muestra la señal resultante:';
            app.GRFICASDEENTRADASALIDALabel = uilabel(app.UIFigure);
            app.GRFICASDEENTRADASALIDALabel.Text = '4. GRÁFICAS DE ENTRADA / SALIDA';
            app.OperacionesDropDownLabel = uilabel(app.UIFigure);
            app.OperacionesDropDownLabel.Text = 'Operaciones:';
            app.OperacionesDropDown = uidropdown(app.UIFigure);
            app.OperacionesDropDown.Items = {'Selecciona una opción...', 'Suma', 'Resta', 'Amplificación', 'Atenuación', 'Reflejo', 'Desplazamiento', 'Diezmación', 'Interpolación a cero', 'Interpolación a escalón', 'Interpolación lineal', 'Convolución'};
            app.OperacionesDropDown.ValueChangedFcn = createCallbackFcn(app, @OperacionesDropDownValueChanged, true);
            app.OperacionesDropDown.Value = 'Selecciona una opción...';
            app.ResultadoButton = uibutton(app.UIFigure, 'push');
            app.ResultadoButton.ButtonPushedFcn = createCallbackFcn(app, @ResultadoButtonPushed, true);
            app.ResultadoButton.Visible = 'off';
            app.ResultadoButton.Text = 'Resultado';
            app.SecuenciaznLabel = uilabel(app.UIFigure);
            app.SecuenciaznLabel.Visible = 'off';
            app.SecuenciaznLabel.Text = 'Secuencia z(n):';
            app.SecuenciaxnLabel = uilabel(app.UIFigure);
            app.SecuenciaxnLabel.Text = 'Secuencia x(n)';
            app.SecuenciahnLabel = uilabel(app.UIFigure);
            app.SecuenciahnLabel.Text = 'Secuencia h(n)';
            app.SecuenciaresultanteznLabel = uilabel(app.UIFigure);
            app.SecuenciaresultanteznLabel.Text = 'Secuencia resultante z(n)';
            app.OrigenxposicinLabel = uilabel(app.UIFigure);
            app.OrigenxposicinLabel.Visible = 'off';
            app.OrigenxposicinLabel.Text = 'Origen x (posición)';
            app.origenx = uieditfield(app.UIFigure, 'numeric');
            app.origenx.Visible = 'off';
            app.OrigenhposicinLabel = uilabel(app.UIFigure);
            app.OrigenhposicinLabel.Visible = 'off';
            app.OrigenhposicinLabel.Text = 'Origen h (posición)';
            app.origenh = uieditfield(app.UIFigure, 'numeric');
            app.origenh.Visible = 'off';
            app.OrigenzposicinLabel = uilabel(app.UIFigure);
            app.OrigenzposicinLabel.Visible = 'off';
            app.OrigenzposicinLabel.Text = 'Origen z (posición)';
            app.origenz = uieditfield(app.UIFigure, 'numeric');
            app.origenz.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = operaciones_final_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end